classdef FinalPart2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam3ProgramUIFigure        matlab.ui.Figure
        OpenDataMenu                matlab.ui.container.Menu
        FinalExambyLabel            matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
        TestDateLabel               matlab.ui.control.Label
        SampleIDLabel               matlab.ui.control.Label
        SampleIDOutputLabel         matlab.ui.control.Label
        minimalvalueEditFieldLabel  matlab.ui.control.Label
        minimalvalueEditField       matlab.ui.control.NumericEditField
        maximalvalueEditFieldLabel  matlab.ui.control.Label
        maximalvalueEditField       matlab.ui.control.NumericEditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        RedButton                   matlab.ui.control.RadioButton
        BlueButton                  matlab.ui.control.RadioButton
        BlackButton                 matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
        TestDateOutputLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        PlotColor = [1 0 0]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenDataMenu
        function OpenDataMenuSelected(app, event)
            [fname, folder] = uigetfile('*.dat');
            if fname == 0
                return;
            end
            fpath = fullfile(folder, fname);
            [sample_ID, date, meas] = FinalPart1(fpath);

            app.NameLabel.Text = 'Abigail Penfield';
            
            SampleID = num2str(sample_ID);
            Date = num2str(date);
            app.TestDateOutputLabel.Text = Date;
            app.SampleIDOutputLabel.Text = SampleID;
            
            min_value = min(meas);
            max_value = max(meas);
            app.minimalvalueEditField.Value = min_value;
            app.maximalvalueEditField.Value = max_value;
          
            bar(meas, 'parent', app.UIAxes, 'FaceColor', app.PlotColor);
            app.UIAxes.Title.String = fname;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case app.RedButton
                    app.PlotColor = [1 0 0];
                case app.BlueButton
                    app.PlotColor = [0 0 1];
                case app.BlackButton
                    app.PlotColor = [0 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam3ProgramUIFigure = uifigure('Visible', 'off');
            app.Exam3ProgramUIFigure.Name = 'Exam-3 Program';
            app.OpenDataMenu = uimenu(app.Exam3ProgramUIFigure);
            app.OpenDataMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDataMenuSelected, true);
            app.OpenDataMenu.Text = 'Open Data';
            app.FinalExambyLabel = uilabel(app.Exam3ProgramUIFigure);
            app.FinalExambyLabel.Text = 'Final Exam by:';
            app.NameLabel = uilabel(app.Exam3ProgramUIFigure);
            app.NameLabel.Text = '';
            app.TestDateLabel = uilabel(app.Exam3ProgramUIFigure);
            app.TestDateLabel.Text = 'Test Date:';
            app.SampleIDLabel = uilabel(app.Exam3ProgramUIFigure);
            app.SampleIDLabel.Text = 'Sample ID:';
            app.SampleIDOutputLabel = uilabel(app.Exam3ProgramUIFigure);
            app.SampleIDOutputLabel.Text = '';
            app.minimalvalueEditFieldLabel = uilabel(app.Exam3ProgramUIFigure);
            app.minimalvalueEditFieldLabel.Text = 'minimal value:';
            app.minimalvalueEditField = uieditfield(app.Exam3ProgramUIFigure, 'numeric');
            app.minimalvalueEditField.ValueDisplayFormat = '%0.1f';
            app.maximalvalueEditFieldLabel = uilabel(app.Exam3ProgramUIFigure);
            app.maximalvalueEditFieldLabel.Text = 'maximal value:';
            app.maximalvalueEditField = uieditfield(app.Exam3ProgramUIFigure, 'numeric');
            app.maximalvalueEditField.ValueDisplayFormat = '%0.1f';
            app.ButtonGroup = uibuttongroup(app.Exam3ProgramUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.RedButton = uiradiobutton(app.ButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.BlueButton = uiradiobutton(app.ButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.BlackButton = uiradiobutton(app.ButtonGroup);
            app.BlackButton.Text = 'Black';
            app.UIAxes = uiaxes(app.Exam3ProgramUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Measurements')
            ylabel(app.UIAxes, '')
            app.TestDateOutputLabel = uilabel(app.Exam3ProgramUIFigure);
            app.TestDateOutputLabel.Text = '';
            % Show the figure after all components are created
            app.Exam3ProgramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalPart2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam3ProgramUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam3ProgramUIFigure)
        end
    end
end