classdef Question4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SimpleCalculatorLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        EditField_R            matlab.ui.control.NumericEditField
        EqualButton            matlab.ui.control.Button
        EditField_L            matlab.ui.control.NumericEditField
        DisplayAns             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_L
        function EditField_LValueChanged(app, event)

            
        end

        % Value changed function: EditField_R
        function EditField_RValueChanged(app, event)

            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

       
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)

            switch app.DropDown.Value
                case '+'
                    answer = app.EditField_L.Value + app.EditField_R.Value;
                case '-'
                    answer = app.EditField_L.Value - app.EditField_R.Value;
                case '*'
                    answer = app.EditField_L.Value * app.EditField_R.Value;
                case '/'
                    answer = app.EditField_L.Value / app.EditField_R.Value;
            end
            app.DisplayAns.Text = sprintf('%.2f', answer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'+', '-', '*', '/'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '+';
            app.EditField_R = uieditfield(app.UIFigure, 'numeric');
            app.EditField_R.ValueChangedFcn = createCallbackFcn(app, @EditField_RValueChanged, true);
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = '=';
            app.EditField_L = uieditfield(app.UIFigure, 'numeric');
            app.EditField_L.ValueChangedFcn = createCallbackFcn(app, @EditField_LValueChanged, true);
            app.DisplayAns = uilabel(app.UIFigure);
            app.DisplayAns.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Question4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end