classdef Question5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Label             matlab.ui.control.Label
        ColorButtonGroup  matlab.ui.container.ButtonGroup
        RedButton         matlab.ui.control.RadioButton
        GreenButton       matlab.ui.control.RadioButton
        BlueButton        matlab.ui.control.RadioButton
        OpenButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        PlotColor = [1 0 0]; %do app.UIAxes.Color instead
    end
    
    methods (Access = private)
        
        function ShowPop(app, filepath)
            d = importdata(filepath);
            year = d.data(:, 1);
            pop = d.data(:, 2);
            plot(app.UIAxes, year, pop, 'Color', app.PlotColor);
            app.UIAxes.YScale = 'log';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

      
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, dirname] = uigetfile('*.txt');
            if filename == 0
                return;
            end
            fp = fullfile(dirname, filename);
            app.Label.Text = fp;
            ShowPop(app, fp);  
        end

        % Selection changed function: ColorButtonGroup
        function ColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorButtonGroup.SelectedObject;
            switch selectedButton
                case app.RedButton
                    app.PlotColor = [1 0 0];
                case app.GreenButton
                    app.PlotColor = [0 1 0];
                case app.BlueButton
                    app.PlotColor = [0 0 1];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Population')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorButtonGroupSelectionChanged, true);
            app.ColorButtonGroup.Title = 'Color';
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Question5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end