classdef Question6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChooseaFileButton              matlab.ui.control.Button
        NumberofFilesCheckedEditFieldLabel  matlab.ui.control.Label
        NumberofFilesCheckedEditField  matlab.ui.control.NumericEditField
        DispFolder                     matlab.ui.control.Label
        DispName                       matlab.ui.control.Label
        DispDate                       matlab.ui.control.Label
        FolderLabel                    matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
        DateCreatedLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        Count;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Count = 0;
        end

        % Button pushed function: ChooseaFileButton
        function ChooseaFileButtonPushed(app, event)
            app.Count = app.Count + 1;
            app.NumberofFilesCheckedEditField.Value = app.Count;
            [filename, dirname] = uigetfile();
            if filename == 0
                return;
            end
            filepath = fullfile(dirname, filename);
            app.DispFolder.Text = filepath;
            app.DispName.Text = filename;
            info = dir(filepath);
            app.DispDate.Text = [info.date];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseaFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseaFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseaFileButtonPushed, true);
            app.ChooseaFileButton.Text = 'Choose a File';
            app.NumberofFilesCheckedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFilesCheckedEditFieldLabel.Text = 'Number of Files Checked';
            app.NumberofFilesCheckedEditField = uieditfield(app.UIFigure, 'numeric');
            app.DispFolder = uilabel(app.UIFigure);
            app.DispFolder.Text = '';
            app.DispName = uilabel(app.UIFigure);
            app.DispName.Text = '';
            app.DispDate = uilabel(app.UIFigure);
            app.DispDate.Text = '';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.DateCreatedLabel = uilabel(app.UIFigure);
            app.DateCreatedLabel.Text = 'Date Created:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Question6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end