classdef Question7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        YourHeightinSliderLabel     matlab.ui.control.Label
        HeightSlider                matlab.ui.control.Slider
        YourWeightlbSliderLabel     matlab.ui.control.Label
        WeightSlider                matlab.ui.control.Slider
        CheckBMIButton              matlab.ui.control.Button
        BMICalculatorLabel          matlab.ui.control.Label
        FtEditFieldLabel            matlab.ui.control.Label
        FeetEditField               matlab.ui.control.NumericEditField
        NameLabel                   matlab.ui.control.Label
        InEditFieldLabel            matlab.ui.control.Label
        InchEditField               matlab.ui.control.NumericEditField
        lbEditFieldLabel            matlab.ui.control.Label
        PoundEditField              matlab.ui.control.NumericEditField
        YourBMIEditFieldLabel       matlab.ui.control.Label
        BMIEditField                matlab.ui.control.NumericEditField
        YourweightisEditFieldLabel  matlab.ui.control.Label
        WeightCategoryEditField     matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: HeightSlider
        function HeightSliderValueChanging(app, event)
            changingValue = event.Value;
            app.FeetEditField.Value = floor(changingValue / 12);
            app.InchEditField.Value = changingValue - 12*floor(changingValue / 12);
        end

        % Value changing function: WeightSlider
        function WeightSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PoundEditField.Value = changingValue;
        end

        % Button pushed function: CheckBMIButton
        function CheckBMIButtonPushed(app, event)
            height = app.FeetEditField.Value * 12 + app.InchEditField.Value;
            weight = app.PoundEditField.Value;
            [result, bgcolor] = CalBMI(weight, height);
            app.BMIEditField.BackgroundColor = bgcolor;
            app.BMIEditField.Value = (weight / height^2) * 703;
            app.WeightCategoryEditField.Value = result;
            app.WeightCategoryEditField.BackgroundColor = bgcolor;
        end

        % Value changed function: BMIEditField
        function BMIEditFieldValueChanged(app, event)
 
        end

        % Value changed function: WeightSlider
        function WeightSliderValueChanged(app, event)

        end

        % Value changed function: PoundEditField
        function PoundEditFieldValueChanged(app, event)
           app.WeightSlider.Value = app.PoundEditField.Value;
            
        end

        % Value changed function: FeetEditField
        function FeetEditFieldValueChanged(app, event)
            app.HeightSlider.Value = app.FeetEditField.Value * 12 + app.InchEditField.Value;
            
        end

        % Value changed function: InchEditField
        function InchEditFieldValueChanged(app, event)
            app.HeightSlider.Value = app.FeetEditField.Value * 12 + app.InchEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourHeightinSliderLabel = uilabel(app.UIFigure);
            app.YourHeightinSliderLabel.Text = 'Your Height (in)';
            app.HeightSlider = uislider(app.UIFigure);
            app.HeightSlider.Limits = [30 100];
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @HeightSliderValueChanging, true);
            app.HeightSlider.Value = 70;
            app.YourWeightlbSliderLabel = uilabel(app.UIFigure);
            app.YourWeightlbSliderLabel.Text = 'Your Weight (lb)';
            app.WeightSlider = uislider(app.UIFigure);
            app.WeightSlider.Limits = [50 300];
            app.WeightSlider.ValueChangedFcn = createCallbackFcn(app, @WeightSliderValueChanged, true);
            app.WeightSlider.ValueChangingFcn = createCallbackFcn(app, @WeightSliderValueChanging, true);
            app.WeightSlider.Value = 150;
            app.CheckBMIButton = uibutton(app.UIFigure, 'push');
            app.CheckBMIButton.ButtonPushedFcn = createCallbackFcn(app, @CheckBMIButtonPushed, true);
            app.CheckBMIButton.Text = 'Check BMI';
            app.BMICalculatorLabel = uilabel(app.UIFigure);
            app.BMICalculatorLabel.Text = 'BMI Calculator';
            app.FtEditFieldLabel = uilabel(app.UIFigure);
            app.FtEditFieldLabel.Text = 'Ft';
            app.FeetEditField = uieditfield(app.UIFigure, 'numeric');
            app.FeetEditField.ValueChangedFcn = createCallbackFcn(app, @FeetEditFieldValueChanged, true);
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Abigail Penfield';
            app.InEditFieldLabel = uilabel(app.UIFigure);
            app.InEditFieldLabel.Text = 'In';
            app.InchEditField = uieditfield(app.UIFigure, 'numeric');
            app.InchEditField.ValueChangedFcn = createCallbackFcn(app, @InchEditFieldValueChanged, true);
            app.lbEditFieldLabel = uilabel(app.UIFigure);
            app.lbEditFieldLabel.Text = 'lb';
            app.PoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoundEditField.ValueChangedFcn = createCallbackFcn(app, @PoundEditFieldValueChanged, true);
            app.YourBMIEditFieldLabel = uilabel(app.UIFigure);
            app.YourBMIEditFieldLabel.Text = 'Your BMI:';
            app.BMIEditField = uieditfield(app.UIFigure, 'numeric');
            app.BMIEditField.ValueChangedFcn = createCallbackFcn(app, @BMIEditFieldValueChanged, true);
            app.YourweightisEditFieldLabel = uilabel(app.UIFigure);
            app.YourweightisEditFieldLabel.Text = 'Your weight is';
            app.WeightCategoryEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Question7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end