classdef Vintage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        OpenImageButton  matlab.ui.control.Button
        Label            matlab.ui.control.Label
        UIAxesR          matlab.ui.control.UIAxes
        UIAxesL          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MyImg; % Description
    end
    
    methods (Access = private)
        
        function Convert(app, filepath)
            app.MyImg = imread(filepath);
            r = app.MyImg(:, :, 1);
            g = app.MyImg(:, :, 2);
            b = app.MyImg(:, :, 3);
            new_r = (r * .393) + (g *.769) + (b * .189);
            new_g = (r * .349) + (g *.686) + (b * .168);
            new_b = (r * .272) + (g *.534) + (b * .131);
            sepia = uint8(cat(3, new_r, new_g, new_b));
            imshow(app.MyImg, 'parent', app.UIAxesL)
            imshow(sepia, 'parent', app.UIAxesR);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            [filename, dirname] = uigetfile();
            if filename == 0
                return;
            end
            filepath = fullfile(dirname, filename);
            app.Label.Text = filepath;
            Convert(app, filepath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.UIAxesR = uiaxes(app.UIFigure);
            title(app.UIAxesR, 'Vintage')
            xlabel(app.UIAxesR, '')
            ylabel(app.UIAxesR, '')
            app.UIAxesR.XTick = [];
            app.UIAxesR.YTick = [];
            app.UIAxesL = uiaxes(app.UIFigure);
            title(app.UIAxesL, 'Original')
            xlabel(app.UIAxesL, '')
            ylabel(app.UIAxesL, '')
            app.UIAxesL.XTick = [];
            app.UIAxesL.YTick = [];
            app.UIAxesL.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vintage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end