classdef Wk7_Q6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorcomponentsUIFigure  matlab.ui.Figure
        OpenImageButton          matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        WidthEditFieldLabel      matlab.ui.control.Label
        WidthEditField           matlab.ui.control.NumericEditField
        HeightEditFieldLabel     matlab.ui.control.Label
        HeightEditField          matlab.ui.control.NumericEditField
        FormatEditFieldLabel     matlab.ui.control.Label
        FormatEditField          matlab.ui.control.EditField
        ColorTypeEditFieldLabel  matlab.ui.control.Label
        ColorTypeEditField       matlab.ui.control.EditField
        RedChannel               matlab.ui.control.UIAxes
        GreenChannel             matlab.ui.control.UIAxes
        BlueChannel              matlab.ui.control.UIAxes
        REDLabel                 matlab.ui.control.Label
        GREENLabel               matlab.ui.control.Label
        BLUELabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        MyImg; % Description
    end
    
    methods (Access = private)
        
        function CheckComponents(app, filepath)
            app.MyImg = imread(filepath);
            info = imfinfo(filepath);
            app.WidthEditField.Value = info.Width;
            app.HeightEditField.Value = info.Height;
            app.ColorTypeEditField.Value = info.ColorType;
            app.FormatEditField.Value = info.Format;
            if info.ColorType == 'grayscale'
                imshow(app.MyImg, 'parent', app.UIAxes)
                imshow(app.MyImg, 'parent', app.RedChannel);
                imshow(app.MyImg, 'parent', app.GreenChannel);
                imshow(app.MyImg, 'parent', app.BlueChannel);
            elseif info.ColorType == 'truecolor'
                r = app.MyImg(:, :, 1);
                g = app.MyImg(:, :, 2);
                b = app.MyImg(:, :, 3);
                allBlack = zeros(size(app.MyImg, 1), size(app.MyImg, 2), 'uint8');
                just_r = cat(3, r, allBlack, allBlack);
                just_g = cat(3, allBlack, g, allBlack);
                just_b = cat(3, allBlack, allBlack, b);
                imshow(app.MyImg, 'parent', app.UIAxes)
                imshow(just_r, 'parent', app.RedChannel);
                imshow(just_g, 'parent', app.GreenChannel);
                imshow(just_b, 'parent', app.BlueChannel);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            [filename, dirname] = uigetfile();
            if filename == 0
                return;
            end
            filepath = fullfile(dirname, filename);
            app.Label.Text = filepath;
            CheckComponents(app, filepath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorcomponentsUIFigure = uifigure('Visible', 'off');
            app.ColorcomponentsUIFigure.Name = 'Color components';
            app.OpenImageButton = uibutton(app.ColorcomponentsUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.Label = uilabel(app.ColorcomponentsUIFigure);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.ColorcomponentsUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WidthEditFieldLabel = uilabel(app.ColorcomponentsUIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.ColorcomponentsUIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.ColorcomponentsUIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ColorcomponentsUIFigure, 'numeric');
            app.FormatEditFieldLabel = uilabel(app.ColorcomponentsUIFigure);
            app.FormatEditFieldLabel.Text = 'Format';
            app.FormatEditField = uieditfield(app.ColorcomponentsUIFigure, 'text');
            app.ColorTypeEditFieldLabel = uilabel(app.ColorcomponentsUIFigure);
            app.ColorTypeEditFieldLabel.Text = 'ColorType';
            app.ColorTypeEditField = uieditfield(app.ColorcomponentsUIFigure, 'text');
            app.RedChannel = uiaxes(app.ColorcomponentsUIFigure);
            title(app.RedChannel, '')
            xlabel(app.RedChannel, '')
            ylabel(app.RedChannel, '')
            app.RedChannel.XTick = [];
            app.RedChannel.YTick = [];
            app.GreenChannel = uiaxes(app.ColorcomponentsUIFigure);
            title(app.GreenChannel, '')
            xlabel(app.GreenChannel, '')
            ylabel(app.GreenChannel, '')
            app.GreenChannel.XTick = [];
            app.GreenChannel.YTick = [];
            app.BlueChannel = uiaxes(app.ColorcomponentsUIFigure);
            title(app.BlueChannel, '')
            xlabel(app.BlueChannel, '')
            ylabel(app.BlueChannel, '')
            app.BlueChannel.XTick = [];
            app.BlueChannel.YTick = [];
            app.REDLabel = uilabel(app.ColorcomponentsUIFigure);
            app.REDLabel.Text = 'RED';
            app.GREENLabel = uilabel(app.ColorcomponentsUIFigure);
            app.GREENLabel.Text = 'GREEN';
            app.BLUELabel = uilabel(app.ColorcomponentsUIFigure);
            app.BLUELabel.Text = 'BLUE';
            % Show the figure after all components are created
            app.ColorcomponentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wk7_Q6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorcomponentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorcomponentsUIFigure)
        end
    end
end