classdef TempData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OpenDataMenu                  matlab.ui.container.Menu
        NumberofMissingDataEditFieldLabel  matlab.ui.control.Label
        NumberofMissingDataEditField  matlab.ui.control.NumericEditField
        ZipcodeEditFieldLabel         matlab.ui.control.Label
        ZipcodeEditField              matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenDataMenu
        function OpenDataMenuSelected(app, event)
            [fname, folder] = uigetfile('*.txt');
            if fname == 0
                return;
            end
            fpath = fullfile(folder, fname);
            [ZIPCODE, DATA] = Wk8_Q4(fpath);

            app.ZipcodeEditField.Value = ZIPCODE;
            
            count = 0;
            for i = 1:length(DATA)
                if isnan(DATA(i))
                    count = count + 1;
                end    
            end
            app.NumberofMissingDataEditField.Value = count;
            
            bar(DATA, 'parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenDataMenu = uimenu(app.UIFigure);
            app.OpenDataMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDataMenuSelected, true);
            app.OpenDataMenu.Text = 'Open Data';
            app.NumberofMissingDataEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofMissingDataEditFieldLabel.Text = 'Number of Missing Data:';
            app.NumberofMissingDataEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZipcodeEditFieldLabel = uilabel(app.UIFigure);
            app.ZipcodeEditFieldLabel.Text = 'Zipcode:';
            app.ZipcodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZipcodeEditField.ValueDisplayFormat = '%.0f';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Month')
            ylabel(app.UIAxes, 'Temperature')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TempData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end